classdef Wanderwellen_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TravellingWaveUIFigure          matlab.ui.Figure
        ProfDrAndreasKilthauLabel       matlab.ui.control.Label
        ArnyWolfgramLabel               matlab.ui.control.Label
        StefanSchtzhoffLabel            matlab.ui.control.Label
        AutorenLabel                    matlab.ui.control.Label
        EinstellungenPanel              matlab.ui.container.Panel
        SpracheSwitch                   matlab.ui.control.Switch
        SpracheSwitchLabel              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ErgebnisdarstellungPanel        matlab.ui.container.Panel
        ZeitlupenfaktorSpinner          matlab.ui.control.Spinner
        sLabel_2                        matlab.ui.control.Label
        DarstellungstartenButton        matlab.ui.control.Button
        ZeitlupenfaktorLabel            matlab.ui.control.Label
        WanderwelleberdieLngederLeitungLabel  matlab.ui.control.Label
        SimulationszeitCheckBox         matlab.ui.control.CheckBox
        AnzeigeSchwingung               matlab.ui.control.Label
        kmmax0kmLabel                   matlab.ui.control.Label
        kmmin0kmLabel                   matlab.ui.control.Label
        ZweiteSchwingungbeicaEditFieldLabel  matlab.ui.control.Label
        ZweiteSchwingungbeicaEditField  matlab.ui.control.NumericEditField
        ErsteSchwingungbeicaEditFieldLabel  matlab.ui.control.Label
        ErsteSchwingungbeicaEditField   matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        DarstellungsortDropDown         matlab.ui.control.DropDown
        DarstellungsortDropDownLabel    matlab.ui.control.Label
        ZeitfensterbreiteSlider         matlab.ui.control.Slider
        ZeitfensterbreiteSliderLabel    matlab.ui.control.Label
        SchwingungamAnfangEndederLeitungLabel  matlab.ui.control.Label
        SimulationsparameterPanel       matlab.ui.container.Panel
        SimulationszeitSwitch           matlab.ui.control.Switch
        cdottauLabel                    matlab.ui.control.Label
        SimulationszeitTauEditField     matlab.ui.control.NumericEditField
        SimulationszeitEditFieldLabel   matlab.ui.control.Label
        SimulationstartenButton         matlab.ui.control.Button
        sLabel                          matlab.ui.control.Label
        SimulationszeitTEditField       matlab.ui.control.NumericEditField
        WellenparameterautomatischberechnetPanel  matlab.ui.container.Panel
        parameter5WertLabel             matlab.ui.control.Label
        parameter5Label                 matlab.ui.control.Label
        parameter6WertLabel             matlab.ui.control.Label
        parameter4WertLabel             matlab.ui.control.Label
        parameter3WertLabel             matlab.ui.control.Label
        parameter2WertLabel             matlab.ui.control.Label
        parameter2Label                 matlab.ui.control.Label
        parameter6Label                 matlab.ui.control.Label
        parameter4Label                 matlab.ui.control.Label
        parameter3Label                 matlab.ui.control.Label
        parameter7WertLabel             matlab.ui.control.Label
        parameter7Label                 matlab.ui.control.Label
        parameter1WertLabel             matlab.ui.control.Label
        parameter1Label                 matlab.ui.control.Label
        BehandlungdesLeitungsendesPanel  matlab.ui.container.Panel
        OmegaLabel_5                    matlab.ui.control.Label
        Abschlussimpedanz_2EditField    matlab.ui.control.NumericEditField
        OmegaLabel_4                    matlab.ui.control.Label
        Abschlussimpedanz_1EditField    matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        AbschlussimpedanzDropDown       matlab.ui.control.DropDown
        LeitungsendeImage               matlab.ui.control.Image
        SpannungsquellePanel            matlab.ui.container.Panel
        QuelleImage                     matlab.ui.control.Image
        QuellenimpedanzDropDown         matlab.ui.control.DropDown
        Quellenimpedanz_1EditField      matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        OmegaLabel_3                    matlab.ui.control.Label
        OmegaquadjLabel                 matlab.ui.control.Label
        Quellenimpedanz_2EditField      matlab.ui.control.NumericEditField
        ULabel                          matlab.ui.control.Label
        kVLabel                         matlab.ui.control.Label
        SpannungEditField               matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        FrequenzEditField               matlab.ui.control.NumericEditField
        fLabel                          matlab.ui.control.Label
        ArtDerSpannungsquelleSwitch     matlab.ui.control.Switch
        StatusLabel                     matlab.ui.control.Label
        LeitungsparameterPanel          matlab.ui.container.Panel
        kmLabel                         matlab.ui.control.Label
        GesamtlngeEditFieldLabel        matlab.ui.control.Label
        GesamtlngeEditField             matlab.ui.control.NumericEditField
        HkmLabel                        matlab.ui.control.Label
        LEditFieldLabel                 matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        FkmLabel                        matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        SkmLabel                        matlab.ui.control.Label
        GLabel                          matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        OmegakmLabel                    matlab.ui.control.Label
        REditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        LeitungsauswahlDropDownLabel    matlab.ui.control.Label
        LeitungsauswahlDropDown         matlab.ui.control.DropDown
        LeitungImage                    matlab.ui.control.Image
    end

    %=====================================================================
    % GLOBALE VARIABLEN
    %=====================================================================

    properties (Access = private)
        LeitungsparameterDatenbank;
        out;
        laenge;
        Schwingung1_km = 0;
        Schwingung2_km = 0;
        Schwingung1_idx = 1;
        Schwingung2_idx = 101;
        Tau;
        TStrings;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %=============================================================
            % AUSFÜHRUNG BEI START DER APP
            %=============================================================
         
            % Excel-Tabelle LeitungsparameterDatenbank einlesen:
                app.LeitungsparameterDatenbank = readtable('Wanderwellen_LeitungsparameterDatenbank.xlsx'); 
            % DropDown-Menü füllen:
                app.LeitungsauswahlDropDown.Items = cellstr(app.LeitungsparameterDatenbank.DropDownName);
            % Es wird die Anzahl der enthaltenen Elemente ermittelt, um
            % ItemsData mit den Zahlenwerten von 0 bis anzLeitungen-1 zu
            % füllen. Auf diese Weise bleibt beim Sprachenwechsel das
            % ausgewählte Element erhalten und wird nicht zurückgesetzt
                [~,anzLeitungen] = size(app.LeitungsauswahlDropDown.Items);
                app.LeitungsauswahlDropDown.ItemsData = 0:anzLeitungen-1;
             
            % Voreinstellung des Zeitfensterwertes an Textfeld übergeben
                app.msLabel.Text = sprintf('%.1f ms',app.ZeitfensterbreiteSlider.Value);
            
            % Dropdownelemente werden hier gesetzt, damit die Elemente
            % griechische Buchstaben enthalten können
                app.QuellenimpedanzDropDown.Items = {'Z = 0', 'R + jX', ['Z' char(183) 'e^j' char(966)], ['R + j' char(969) 'L'], ['R + 1/j' char(969) 'C']};
                app.QuellenimpedanzDropDown.ItemsData = {'0', '1', '2', '3', '4'};
                app.QuellenimpedanzDropDown.Value = '0';
    
                app.AbschlussimpedanzDropDown.Items = {'Leerlauf', 'Kurzschluss', 'R + jX', ['Z' char(183) 'e^j' char(966)], ['R + j' char(969) 'L'], ['R + 1/j' char(969) 'C']};
                app.AbschlussimpedanzDropDown.ItemsData = {'0', '1', '2', '3', '4', '5'};
                app.AbschlussimpedanzDropDown.Value = '0';
                LeitungsParameterEditFieldValueChanged(app);
    
                app.SimulationszeitSwitch.Items = {char([0xD835 0xDF0F]), 's'};
                app.SimulationszeitSwitch.ItemsData = [0 1];
                app.SimulationszeitSwitch.Value = 0;
    
                app.ZeitlupenfaktorLabel.Text = [char([0xD835 0xDF0F]) ' ' char(0x2259)];
            % Sprache setzen
                SpracheSwitchValueChanged(app);
            
            % Simulations-Status setzen:
                app.StatusLabel.Text = app.TStrings.ready;
        end

        % Button pushed function: DarstellungstartenButton
        function DarstellungstartenButtonPushed(app, event)
            %=============================================================
            % BUTTON GEDRÜCKT: "Darstellung starten"
            %=============================================================
            
            % Darstellungsparameter an den Workspace übergeben
                assignin('base','laenge',app.laenge);
                if app.SimulationszeitCheckBox.Value == 0
                    % Fall: Zeitfensterbreite wurde mit Slider eingestellt
                    assignin('base','Zeitfensterbreite',app.ZeitfensterbreiteSlider.Value);
                else
                    % Fall: Zeitfensterbreite = Simulationszeit
                    assignin('base','Zeitfensterbreite',app.SimulationszeitTEditField.Value*1000);
                end
                assignin('base','ErsteSchwingung',app.Schwingung1_idx);
                assignin('base','ZweiteSchwingung',app.Schwingung2_idx);
                assignin('base','Zeitlupenfaktor',app.ZeitlupenfaktorSpinner.Value);
                assignin('base','Tau',app.Tau);
                assignin('base','Language',app.SpracheSwitch.Value);
                
            % .m-File für die Darstellung ausführen
            try
                evalin('base','Wanderwellen_Darstellung');
            catch ME
                app.StatusLabel.Text = [app.TStrings.presentationfailed ME.message];
            end
                
            % Status aktualisieren
                app.StatusLabel.Text = app.TStrings.ready;
        end

        % Value changed function: LeitungsauswahlDropDown
        function LeitungsauswahlDropDownValueChanged(app, event)
            %=============================================================
            % DROPDOWN GEÄNDERT: "Leitungsauswahl"
            %=============================================================

            % Auslesen, was im DropDown-Menü ausgewählt ist
                index = app.LeitungsauswahlDropDown.Value;

            % Füllen der Parameter-Eingabefelder nach DropDown-Auswahl
                if index ~= 0
                    app.REditField.Value = app.LeitungsparameterDatenbank.Widerstandsbelag_Ohm_km(index+1,1);
                    app.GEditField.Value = app.LeitungsparameterDatenbank.Ableitungsbelag_S_km(index+1,1);
                    app.CEditField.Value = app.LeitungsparameterDatenbank.Kapazitaetsbelag_F_km(index+1,1);
                    app.LEditField.Value = app.LeitungsparameterDatenbank.Induktivitaetsbelag_H_km(index+1,1);
                    app.SpannungEditField.Value = app.LeitungsparameterDatenbank.Spannung_kV(index+1,1);
                    app.GesamtlngeEditField.Value = app.LeitungsparameterDatenbank.Laenge_km(index+1,1);
                end
                
            % Bearbeitbarkeit der Parameter-Eingabefelder beschränken
                if index == 0
                    % Fall: individuelle Eingabe
                    app.REditField.Editable = 'on';
                    app.GEditField.Editable = 'on';
                    app.CEditField.Editable = 'on';
                    app.LEditField.Editable = 'on';
                    app.SpannungEditField.Editable = 'on';
                else
                    % Fall: voreingestellte Parameter
                    app.REditField.Editable = 'off';
                    app.GEditField.Editable = 'off';
                    app.CEditField.Editable = 'off';
                    app.LEditField.Editable = 'off';
                    app.SpannungEditField.Editable = 'off';
                end
            GesamtlngeEditFieldValueChanged(app);
        end

        % Value changed function: AbschlussimpedanzDropDown
        function AbschlussimpedanzDropDownValueChanged(app, event)
            %=============================================================
            % DROPDOWN GEÄNDERT: "Abschlussimpedanz"
            %=============================================================
            % Darstellungspanel deaktivieren
                app.ErgebnisdarstellungPanel.Enable = "off";
            % Auslesen, was im DropDown-Menü ausgewählt ist (nach Index)
                Z_abschluss_type_auswahl = str2double(app.AbschlussimpedanzDropDown.Value);
            
            % Bearbeitbarkeit der Felder einschränken
                if Z_abschluss_type_auswahl == 0 || Z_abschluss_type_auswahl == 1
                    % Fall: Leerlauf oder Kurzschluss
                    val = [Inf,0];
                    img = ["Leitungsende_Leerlauf.png","Leitungsende_Kurzschluss.png"];
                    app.Abschlussimpedanz_1EditField.Editable = 'off';
                    app.Abschlussimpedanz_2EditField.Editable = 'off';
                    app.Abschlussimpedanz_1EditField.Visible = 'off';
                    app.Abschlussimpedanz_2EditField.Visible = 'off';
                    app.OmegaLabel_4.Visible = 'off';
                    app.OmegaLabel_5.Visible = 'off';
                    app.ZLabel_2.Visible = 'off';
                    app.Abschlussimpedanz_1EditField.Value = val(Z_abschluss_type_auswahl+1);
                    app.Abschlussimpedanz_2EditField.Value = val(Z_abschluss_type_auswahl+1);
                    app.LeitungsendeImage.ImageSource = img(Z_abschluss_type_auswahl+1);
                else
                    % Fall: nicht Leerlauf und nicht Kurzschluss
                    app.Abschlussimpedanz_1EditField.Visible = 'on';
                    app.Abschlussimpedanz_2EditField.Visible = 'on';
                    app.OmegaLabel_4.Visible = 'on';
                    app.OmegaLabel_5.Visible = 'on';
                    app.ZLabel_2.Visible = 'on';
                    app.Abschlussimpedanz_1EditField.Editable = 'on';
                    app.Abschlussimpedanz_2EditField.Editable = 'on';
                    app.LeitungsendeImage.ImageSource = 'Leitungsende_Impedanz.png';
                end
            
            % Texte und Einheiten entsprechend der Auswahl anpassen
                % Fall: R & X
                if Z_abschluss_type_auswahl == 2
                    app.OmegaLabel_4.Text = "$\Omega\quad$+j";
                    app.OmegaLabel_5.Text = "$\Omega$";
                end
                % Fall: Z & phi
                if Z_abschluss_type_auswahl == 3
                    app.OmegaLabel_4.Text = "$\Omega$  $\cdot$e^j";
                    app.OmegaLabel_5.Text = "°";
                end
                % Fall: R & L
                if Z_abschluss_type_auswahl == 4
                    app.OmegaLabel_4.Text = "$\Omega$  +j$\omega$";
                    app.OmegaLabel_5.Text = "H";
                end
                % Fall: R & C
                if Z_abschluss_type_auswahl == 5
                    app.OmegaLabel_4.Text = "$\Omega$+1/j$\omega$";
                    app.OmegaLabel_5.Text = "F";
                end
        end

        % Button pushed function: SimulationstartenButton
        function SimulationstartenButtonPushed(app, event)
            %=============================================================
            % BUTTON GEDRÜCKT: "Simulation starten"
            %=============================================================
            
            % Eingabeparameter einlesen, die die Simulation beeinflussen
            %-------------------------------------------------------------
                R_belag = app.REditField.Value;
                G_belag = app.GEditField.Value;
                C_belag = app.CEditField.Value;
                L_belag = app.LEditField.Value;
                app.laenge = app.GesamtlngeEditField.Value / 100;
                U_type = str2double(app.ArtDerSpannungsquelleSwitch.Value);
                U = app.SpannungEditField.Value*1000;
                clear f;
                f = app.FrequenzEditField.Value;
                Z_quelle_type_auswahl = str2double(app.QuellenimpedanzDropDown.Value);
                Quelle_Impedanz1_eingabe = app.Quellenimpedanz_1EditField.Value;
                Quelle_Impedanz2_eingabe = app.Quellenimpedanz_2EditField.Value;
                Z_abschluss_type_auswahl = str2double(app.AbschlussimpedanzDropDown.Value);
                Abschluss_Impedanz1_eingabe = app.Abschlussimpedanz_1EditField.Value;
                Abschluss_Impedanz2_eingabe = app.Abschlussimpedanz_2EditField.Value;
                t_sim = app.SimulationszeitTEditField.Value;

            % Eingabeparameter an Workspace übergeben
            %-------------------------------------------------------------
                if U_type == 0
                    f = 1;  % darf nicht 0 sein (bei DC keine Eingabe)     
                end
                
            % Berechnen der konkreten Werte der Quellenimpedanz
            %-------------------------------------------------------------
                % Fall: Kurzschluss   
                if Z_quelle_type_auswahl == 0
                    Z_quelle_type = 1;  % Schalterstellung in Simulation
                    R_quelle = 0;
                    C_quelle = 1;  % darf nicht 0 sein
                    L_quelle = 1;  % darf nicht 0 sein
                end
                % Fall: R & X
                if Z_quelle_type_auswahl == 1
                    if Quelle_Impedanz2_eingabe == 0
                        % Fall: Nur Resistanz
                        Z_quelle_type = 1;  % Schalterstellung in Simulation
                        R_quelle = Quelle_Impedanz1_eingabe;
                        C_quelle = 1;  % darf nicht 0 sein
                        L_quelle = 1;  % darf nicht 0 sein
                    else
                        if Quelle_Impedanz2_eingabe > 0
                            % Fall: Reaktanz ist positiv
                            Z_quelle_type = 2;  % Schalterstellung in Simulation
                            R_quelle = Quelle_Impedanz1_eingabe;
                            C_quelle = 1;  % darf nicht 0 sein
                            L_quelle = Quelle_Impedanz2_eingabe / (2*pi*f);  % Berechnung der Induktivität 
                        else
                            % Fall: Reaktanz ist negativ
                            Z_quelle_type = 3;  % Schalterstellung in Simulation
                            R_quelle = Quelle_Impedanz1_eingabe;
                            C_quelle = 1 / (-1 * Quelle_Impedanz2_eingabe * 2*pi*f);  % Berechnung der Kapazität
                            L_quelle = 1;  % darf nicht 0 sein
                        end
                    end
                end
                % Fall: Z & phi
                if Z_quelle_type_auswahl == 2
                    % Umrechnung in die Form: R +j X
                    R_quelle_berechnet = Quelle_Impedanz1_eingabe * cos(Quelle_Impedanz2_eingabe);
                    X_quelle_berechnet = Quelle_Impedanz1_eingabe * sin(Quelle_Impedanz2_eingabe);
                    % Zuweisen der Impedanz-Parameter
                    if X_quelle_berechnet == 0
                        % Fall: Nur Resistanz
                        Z_quelle_type = 1;  % Schalterstellung in Simulation
                        R_quelle = R_quelle_berechnet;
                        C_quelle = 1;  % darf nicht 0 sein
                        L_quelle = 1;  % darf nicht 0 sein
                    else
                        if X_quelle_berechnet > 0
                            % Fall: Reaktanz ist positiv
                            Z_quelle_type = 2;  % Schalterstellung in Simulation
                            R_quelle = R_quelle_berechnet;
                            C_quelle = 1;  % darf nicht 0 sein
                            L_quelle = X_quelle_berechnet / (2*pi*f);  % Berechnung der Induktivität 
                        else
                            % Fall: Reaktanz ist negativ
                            Z_quelle_type = 3;  % Schalterstellung in Simulation
                            R_quelle = R_quelle_berechnet;
                            C_quelle = 1 / (-1 * X_quelle_berechnet * 2*pi*f);  % Berechnung der Kapazität
                            L_quelle = 1;  % darf nicht 0 sein
                        end
                    end
                end
                % Fall: R & L
                if Z_quelle_type_auswahl == 3
                    Z_quelle_type = 2;  % Schalterstellung in Simulation
                    R_quelle = Quelle_Impedanz1_eingabe;
                    C_quelle = 1;  % darf nicht 0 sein
                    L_quelle = Quelle_Impedanz2_eingabe;
                end
                % Fall: R & C
                if Z_quelle_type_auswahl == 4
                    Z_quelle_type = 3;  % Schalterstellung in Simulation
                    R_quelle = Quelle_Impedanz1_eingabe;
                    C_quelle = Quelle_Impedanz2_eingabe;
                    L_quelle = 1;  % darf nicht 0 sein
                end
                
            % Berechnen der konkreten Werte der Abschlussimpedanz
            %-------------------------------------------------------------
                % Fall: Leerlauf
                if Z_abschluss_type_auswahl == 0
                    Z_abschluss_type = 1;  % Schalterstellung in Simulation
                    R_impedanz = 0;
                    C_impedanz = 1;  % darf nicht 0 sein
                    L_impedanz = 1;  % darf nicht 0 sein
                end
                % Fall: Kurzschluss   
                if Z_abschluss_type_auswahl == 1
                    Z_abschluss_type = 2;  % Schalterstellung in Simulation
                    R_impedanz = 0;
                    C_impedanz = 1;  % darf nicht 0 sein
                    L_impedanz = 1;  % darf nicht 0 sein
                end
                % Fall: R & X
                if Z_abschluss_type_auswahl == 2
                    if Abschluss_Impedanz2_eingabe == 0
                        % Fall: Nur Resistanz
                        Z_abschluss_type = 2;  % Schalterstellung in Simulation
                        R_impedanz = Abschluss_Impedanz1_eingabe;
                        C_impedanz = 1;  % darf nicht 0 sein
                        L_impedanz = 1;  % darf nicht 0 sein
                    else
                        if Abschluss_Impedanz2_eingabe > 0
                            % Fall: Reaktanz ist positiv
                            Z_abschluss_type = 3;  % Schalterstellung in Simulation
                            R_impedanz = Abschluss_Impedanz1_eingabe;
                            C_impedanz = 1;  % darf nicht 0 sein
                            L_impedanz = Abschluss_Impedanz2_eingabe / (2*pi*f);  % Berechnung der Induktivität 
                        else
                            % Fall: Reaktanz ist negativ
                            Z_abschluss_type = 4;  % Schalterstellung in Simulation
                            R_impedanz = Abschluss_Impedanz1_eingabe;
                            C_impedanz = 1 / (-1 * Abschluss_Impedanz2_eingabe * 2*pi*f);  % Berechnung der Kapazität
                            L_impedanz = 1;  % darf nicht 0 sein
                        end
                    end
                end
                % Fall: Z & phi
                if Z_abschluss_type_auswahl == 3
                    % Umrechnung in die Form: R +j X
                    R_abschluss_berechnet = Abschluss_Impedanz1_eingabe * cos(Abschluss_Impedanz2_eingabe);
                    X_abschluss_berechnet = Abschluss_Impedanz1_eingabe * sin(Abschluss_Impedanz2_eingabe);
                    % Zuweisen der Impedanz-Parameter
                    if X_abschluss_berechnet == 0
                        % Fall: Nur Resistanz
                        Z_abschluss_type = 2;  % Schalterstellung in Simulation
                        R_impedanz = R_abschluss_berechnet;
                        C_impedanz = 1;  % darf nicht 0 sein
                        L_impedanz = 1;  % darf nicht 0 sein
                    else
                        if X_abschluss_berechnet > 0
                            % Fall: Reaktanz ist positiv
                            Z_abschluss_type = 3;  % Schalterstellung in Simulation
                            R_impedanz = R_abschluss_berechnet;
                            C_impedanz = 1;  % darf nicht 0 sein
                            L_impedanz = X_abschluss_berechnet / (2*pi*f);  % Berechnung der Induktivität 
                        else
                            % Fall: Reaktanz ist negativ
                            Z_abschluss_type = 4;  % Schalterstellung in Simulation
                            R_impedanz = R_abschluss_berechnet;
                            C_impedanz = 1 / (-1 * X_abschluss_berechnet * 2*pi*f);  % Berechnung der Kapazität
                            L_impedanz = 1;  % darf nicht 0 sein
                        end
                    end
                end
                % Fall: R & L
                if Z_abschluss_type_auswahl == 4
                    Z_abschluss_type = 3;  % Schalterstellung in Simulation
                    R_impedanz = Abschluss_Impedanz1_eingabe;
                    C_impedanz = 1;  % darf nicht 0 sein
                    L_impedanz = Abschluss_Impedanz2_eingabe;
                end
                % Fall: R & C
                if Z_abschluss_type_auswahl == 5
                    Z_abschluss_type = 4;  % Schalterstellung in Simulation
                    R_impedanz = Abschluss_Impedanz1_eingabe;
                    C_impedanz = Abschluss_Impedanz2_eingabe;
                    L_impedanz = 1;  % darf nicht 0 sein
                end
                
            %------------------------------------------------------------- 
                
            % Simulations-Status aktualisieren und DarstellungsPanel
            % blockieren
                app.ErgebnisdarstellungPanel.Enable = "off";
                app.StatusLabel.Text = app.TStrings.performsimulation;
                drawnow;
                
            % Simulation durchführen
                try
                    % Mithilfe des SimulationInput-Objektes können alle
                    % Variablen und Modellparameter eingestellt werden
                    simInp = Simulink.SimulationInput('Wanderwellen_Leitungsmodell');
                    if G_belag == 0
                        G_belag = 1e-08;
                    end
                    % Übergeben aller Simulationsparameter
                    % Wichtig: setVariable('var',var) würde in den base
                    % Workspace schreiben. In der Simulation sind jedoch
                    % die Variablen im Model Workspace hinterlegt und
                    % vorinitialisiert. Mit den Parametern
                    % 'Workspace','Wanderwellen_Leitungsmodell' werden die
                    % Werte aus dem Model Workspace überschrieben.
                    simInp = simInp.setVariable('Delta_R',R_belag*app.laenge,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('Delta_G',1/(G_belag*app.laenge),'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('Delta_C',C_belag*app.laenge,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('Delta_L',L_belag*app.laenge,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('laenge',app.laenge,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('U_type',U_type,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('U',U,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('f',f,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('Z_abschluss_type',Z_abschluss_type,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('R_impedanz',R_impedanz,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('C_impedanz',C_impedanz,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('L_impedanz',L_impedanz,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('Z_quelle_type',Z_quelle_type,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('R_quelle',R_quelle,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('C_quelle',C_quelle,'Workspace','Wanderwellen_Leitungsmodell');
                    simInp = simInp.setVariable('L_quelle',L_quelle,'Workspace','Wanderwellen_Leitungsmodell');
                    
                    % Übergeben aller Modelparameter
                    simInp = simInp.setModelParameter('StartTime','0');
                    simInp = simInp.setModelParameter('StopTime',num2str(t_sim));
                    simInp = simInp.setModelParameter('Solver','ode23t');
                    simInp = simInp.setModelParameter('FixedStep',num2str(round(t_sim/2500)));
                    
                    % ConfigureForDeployment setzt den SimulationMode und
                    % RapidAcceleratorUpToDateCheck so, dass die App als
                    % Standalone App compiliert werden kann.
                    % WICHTIG: Es kann folgender Fehler erscheinen:
                    %'Error occurred while simulating the model 'Wanderwellen_Leitungsmodell' in rapid accelerator mode. Rerun the simulation in normal mode to diagnose this error. Cannot log Simscape data for the model 'Wanderwellen_Leitungsmodell': Das System kann die angegebene Datei nicht finden.'
                    % Dann wie folgt vorgehen:
                    % A. Zeile simInp = simulink.compiler.configureForDeployment(simInp); auskommentieren
                    % B. Programm ausführen und eine Simulation durchführen
                    % C. Zeile von A wieder einkommentieren
                    % D. Programm funktioniert wieder

                    %simInp = simInp.setModelParameter('SimulationMode', 'rapid-accelerator');
                    %simInp = simInp.setModelParameter('RapidAcceleratorUpToDateCheck', 'off');
                    %simInp = simulink.compiler.configureForDeployment(simInp);

                    app.out = sim(simInp);
                    assignin('base','out',app.out);

                    app.ErgebnisdarstellungPanel.Enable = "on";
                    app.StatusLabel.Text = app.TStrings.simulationcompleted;
                    drawnow;
                    pause(5);
                    app.StatusLabel.Text = app.TStrings.ready;
                    SimulationszeitCheckBoxValueChanged(app);
                catch ME
                    ME.message
                    app.StatusLabel.Text = [app.TStrings.simulationfailed ME.message];
                end
            % Status aktualisieren und DarstellungsPanel freischalten
                drawnow;
        end

        % Value changed function: ZeitfensterbreiteSlider
        function ZeitfensterbreiteSliderValueChanged(app, event)
            %=============================================================
            % SLIDER GEÄNDERT: "Zeitfensterbreite"
            %=============================================================
            
            % Aktuellen Slider-Wert einlesen
                zeitfenster = app.ZeitfensterbreiteSlider.Value;
                
            % Bestimmen, welcher Sliderwert einem ganzen oder halben Wert am nächsten Wert
                % (ausgehend von den eingestellten MinorTicks)
                [~, idx] = min(abs(zeitfenster - event.Source.MinorTicks(:)));
                
            % Silderwert auf den gerundten Wert festsetzen
                event.Source.Value = event.Source.MinorTicks(idx);
                
            % Sliderwert erneut auslesen
                zeitfenster = event.Source.MinorTicks(idx);
                
            % Sliderwert an Textfenster übergeben
                app.msLabel.Text = sprintf('%.1f ms',zeitfenster);
        end

        % Value changed function: DarstellungsortDropDown
        function DarstellungsortDropDownValueChanged(app, event)
            %=============================================================
            % DROPDOWN GEÄNDERT: "Darstellungsort"
            %=============================================================
            
            % Auslesen, was im DropDown-Menü ausgewählt ist (nach Index)
                Darstellungsort = str2double(app.DarstellungsortDropDown.Value);
            
            % Bearbeitbarkeit der Felder einschränken & Werte voreinstellen
                if Darstellungsort == 0
                    % Fall: Anfang & Ende der Leitung
                    app.ErsteSchwingungbeicaEditField.Value = 0;
                    app.Schwingung1_km = 0;
                    app.Schwingung1_idx = 1;
                    app.ZweiteSchwingungbeicaEditField.Value = app.GesamtlngeEditField.Value;
                    app.Schwingung2_km = app.GesamtlngeEditField.Value;
                    app.Schwingung2_idx = 101;
                    app.ErsteSchwingungbeicaEditField.Editable = 'off';
                    app.ZweiteSchwingungbeicaEditField.Editable = 'off';
                else
                    % Fall: Individuelle Eingabe
                    app.ErsteSchwingungbeicaEditField.Editable = 'on';
                    app.ZweiteSchwingungbeicaEditField.Editable = 'on';
                end
                
            % Anzeige der Schwingungsorte bei Anfang & Ende anpassen
                % Würde sonst nicht geändert werden, da der km-Wert nicht
                % vom Nutzer geändert wird
                if Darstellungsort == 0
                    app.AnzeigeSchwingung.Text = sprintf([char(0x21D2) app.TStrings.showoscillationsatand],app.Schwingung1_km,app.Schwingung2_km);
                end
        end

        % Value changed function: GesamtlngeEditField
        function GesamtlngeEditFieldValueChanged(app, event)
            %=============================================================
            % EINGABEFELD GEÄNDERT: "Gesamtlänge"
            %=============================================================
            
            % Länge als maximal möglichen Endwert bei der
            % Schwingungsauswahl angeben
                l = app.GesamtlngeEditField.Value;
                app.kmmax0kmLabel.Text = sprintf('km (max. %.1d km)',l);
                app.ZweiteSchwingungbeicaEditField.Value = l;
                app.Schwingung1_km = 0;
                app.Schwingung2_km = l;
                if str2double(app.DarstellungsortDropDown.Value) == 0
                    app.AnzeigeSchwingung.Text = sprintf([char(0x21D2) app.TStrings.showoscillationsatand],app.Schwingung1_km,app.Schwingung2_km);
                end
                LeitungsParameterEditFieldValueChanged(app);
        end

        % Value changed function: ErsteSchwingungbeicaEditField
        function ErsteSchwingungbeicaEditFieldValueChanged(app, event)
            %=============================================================
            % EINGABEFELD GEÄNDERT: "Erste Schwingung bei ca."
            %=============================================================
            
            % Eingegebenen Soll-km-Wert der ersten Schwingung auslesen
                Schwingung1_km_eingabe = app.ErsteSchwingungbeicaEditField.Value;
            % Aktuelle Gesamtlängeneingabe auslesen
                l = app.GesamtlngeEditField.Value;
                
            % Vektor mit den einzelnen km-Stellen, an denen sich eine
            % Messeinheit befindet, erstellen
                x_vektor = 0:(l/100):l;
                
            % Soll-km-Wert mit Vektor vergleichen
            % Ziel: Index mit geringster Abweichung feststellen
                [~,app.Schwingung1_idx] = min(abs(x_vektor-Schwingung1_km_eingabe));
                
            % Nächsten km-Wert festsetzen und ausgeben
                app.Schwingung1_km = x_vektor(1,app.Schwingung1_idx);
                app.AnzeigeSchwingung.Text = sprintf([char(0x21D2) app.TStrings.showoscillationsatand],app.Schwingung1_km,app.Schwingung2_km);
        end

        % Value changed function: ZweiteSchwingungbeicaEditField
        function ZweiteSchwingungbeicaEditFieldValueChanged(app, event)
            %=============================================================
            % EINGABEFELD GEÄNDERT: "Zweite Schwingung bei ca."
            %=============================================================
            
            % Eingegebenen Soll-km-Wert der zweiten Schwingung auslesen
                Schwingung2_km_eingabe = app.ZweiteSchwingungbeicaEditField.Value;
            % Aktuelle Längeeingabe auslesen
                l = app.GesamtlngeEditField.Value;
                
            % Vektor mit den einzelnen km-Stellen, an denen sich eine
            % Messeinheit befindet, erstellen
                x_vektor = 0:(l/100):l;
                
            % Soll-km-Wert mit Vektor vergleichen
            % Ziel: Index mit geringster Abweichung feststellen
                [~,app.Schwingung2_idx] = min(abs(x_vektor-Schwingung2_km_eingabe));
                
            % Nächsten km-Wert festsetzen und ausgeben
                app.Schwingung2_km = x_vektor(1,app.Schwingung2_idx);
                app.AnzeigeSchwingung.Text = sprintf([char(0x21D2) app.TStrings.showoscillationsatand],app.Schwingung1_km,app.Schwingung2_km);
        end

        % Value changed function: QuellenimpedanzDropDown
        function QuellenimpedanzDropDownValueChanged(app, event)
            %=============================================================
            % DROPDOWN GEÄNDERT: "Quellenimpedanz"
            %=============================================================
            
            % Darstellungspanel deaktivieren
                app.ErgebnisdarstellungPanel.Enable = "off";
            % Auslesen, was im DropDown-Menü ausgewählt ist (nach Index)
                Z_quelle_type_auswahl = str2double(app.QuellenimpedanzDropDown.Value);
            
            % Bearbeitbarkeit der Felder einschränken
                if Z_quelle_type_auswahl == 0
                    % Fall: Z = 0
                    app.Quellenimpedanz_1EditField.Editable = 'off';
                    app.Quellenimpedanz_2EditField.Editable = 'off';
                    app.Quellenimpedanz_1EditField.Value = 0;
                    app.Quellenimpedanz_2EditField.Value = 0;
                    app.OmegaquadjLabel.Text = "$\Omega\quad$+j";
                    app.OmegaLabel_3.Text = "$\Omega$";
                else
                    % Fall: Z != 0
                    app.Quellenimpedanz_1EditField.Editable = 'on';
                    app.Quellenimpedanz_2EditField.Editable = 'on';
                end
            
            % Text entsprechend der Auswahl anpassen
                % Fall: R & X
                if Z_quelle_type_auswahl == 1
                    app.OmegaquadjLabel.Text = "$\Omega\quad$+j";
                    app.OmegaLabel_3.Text = "$\Omega$";
                end
                % Fall: Z & phi
                if Z_quelle_type_auswahl == 2
                    app.OmegaquadjLabel.Text = "$\Omega$  $\cdot$e^j";
                    app.OmegaLabel_3.Text = "°";
                end
                % Fall: R & L
                if Z_quelle_type_auswahl == 3
                    app.OmegaquadjLabel.Text = "$\Omega$  +j$\omega$";
                    app.OmegaLabel_3.Text = "H";
                end
                % Fall: R & C
                if Z_quelle_type_auswahl == 4
                    app.OmegaquadjLabel.Text = "$\Omega$+1/j$\omega$";
                    app.OmegaLabel_3.Text = "F";
                end
        end

        % Value changed function: SimulationszeitCheckBox
        function SimulationszeitCheckBoxValueChanged(app, event)
            %=============================================================
            % CHECKBOT GEÄNDERT: "Quellenimpedanz"
            %=============================================================
            
            % Auslesen, welchen Status die CheckBox hat
                simzeit_auswahl = app.SimulationszeitCheckBox.Value;
            
            % Bearbeitbarkeit des Auswahlsliders einschränken, wenn die
            % Zeitfensterbreite der Simulationszeit entsprechen soll
                if simzeit_auswahl == 0
                    app.ZeitfensterbreiteSlider.Enable = 'on';
                else
                    app.ZeitfensterbreiteSlider.Enable = 'off';
                end
            
            % Die Zeitfensterbreiten-Zeit-Anzeige entsprechend anpassen
                if simzeit_auswahl == 0
                    app.msLabel.Text = sprintf('%.1f ms',app.ZeitfensterbreiteSlider.Value);
                else
                    app.msLabel.Text = sprintf('%.1f ms',app.SimulationszeitTEditField.Value*1000);
                end
        end

        % Value changed function: ArtDerSpannungsquelleSwitch
        function ArtDerSpannungsquelleSwitchValueChanged(app, event)
            %=============================================================
            % DROPDOWN GEÄNDERT: "Art der Spannungsquelle"
            %=============================================================

            U_type = str2double(app.ArtDerSpannungsquelleSwitch.Value);
            
            % Bearbeitbarkeit des Frequenzeingabefeldes einschränken
            if U_type == 0
                % Fall: DC
                app.FrequenzEditField.Editable = 'off';
                app.FrequenzEditField.Value = 0;
                app.FrequenzEditField.Visible = "off";
                app.fLabel.Visible = "off";
                app.HzLabel.Visible = "off";
                app.QuelleImage.ImageSource = 'Quelle_DC.png';
            else
                % Fall: AC
                app.fLabel.Visible = "on";
                app.HzLabel.Visible = "on";
                app.FrequenzEditField.Visible = "on";
                app.FrequenzEditField.Editable = 'on';
                app.FrequenzEditField.Value = 50;   % Voreinstellung
                app.QuelleImage.ImageSource = 'Quelle_AC.png';
            end
            LeitungsParameterEditFieldValueChanged(app);
            SpracheSwitchValueChanged(app);
        end

        % Value changed function: CEditField, FrequenzEditField, 
        % ...and 4 other components
        function LeitungsParameterEditFieldValueChanged(app, event)
            %=============================================================
            % PARAMETER GEÄNDERT: Einfluss auf berechnete Wellenparameter
            %=============================================================
            
            U = app.SpannungEditField.Value;
            f = app.FrequenzEditField.Value;
            R = app.REditField.Value;
            G = app.GEditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            l = app.GesamtlngeEditField.Value;
            
            U_type = str2double(app.ArtDerSpannungsquelleSwitch.Value);

            if U_type == 0
                % Fall DC
                % Leitungswellenimpedanz berechnen
                    Zw = sqrt(L/C);
                % Wellengeschwindigkeit berechnen
                    v = 1/sqrt(L*C);
                % Laufzeit berechnen
                    app.Tau = l/v;
                % Werte an die Textfelder übergeben
                    app.parameter1Label.Text = '$Z_W=$';
                    app.parameter2Label.Text = '$v=$';
                    app.parameter3Label.Text = '$\tau=$';
                    app.parameter4Label.Text = '';
                    app.parameter5Label.Text = '';
                    app.parameter6Label.Text = '';
                    app.parameter7Label.Text = '';
                    app.parameter1WertLabel.Text = [sprintf('%.1f',Zw) ' $ \Omega$ '];
                    app.parameter2WertLabel.Text = [sprintf('%.3f',v/299792.458) '$\cdot c_0$'];
                    app.parameter3WertLabel.Text = [sprintf('%.1f',1000000*app.Tau) ' $\mu s$'];
                    app.parameter4WertLabel.Text = '';
                    app.parameter5WertLabel.Text = '';
                    app.parameter6WertLabel.Text = '';
                    app.parameter7WertLabel.Text = '';
            else
                % Fall AC
                % Leitungswellenimpedanz berechnen
                    Zw = sqrt((R+1i*2*pi*f*L)/(G+1i*2*pi*f*C));
                % Komplexen Ausbreitungskoeffizient berechnen
                    gamma = sqrt((R+1i*2*pi*f*L)*(G+1i*2*pi*f*C));
                % Natürliche Leistung berechnen
                    Pn = (U^2)/(1000*Zw);
                % Werte an die Textfelder übergeben
                    vz = ['-','+','+'];
                    app.parameter1Label.Text = '$\underline{Z}_W=$';
                    app.parameter2Label.Text = '$|\underline{Z}_W|=$';
                    app.parameter3Label.Text = '$\underline\gamma=$';
                    app.parameter4Label.Text = '$\lambda=$';
                    app.parameter5Label.Text = '$v=$';
                    app.parameter6Label.Text = '$\tau=$';
                    app.parameter7Label.Text = '$P_{nat}=$';
                    app.parameter1WertLabel.Text = [sprintf('%.1f',real(Zw)) ' $ \Omega$ ' vz(sign(imag(Zw))+2) sprintf('j %.1f',abs(imag(Zw))) ' $\Omega$'];
                    app.parameter2WertLabel.Text = [sprintf('%.1f',abs(Zw)) ' $\Omega$'];
                    app.parameter3WertLabel.Text = [sprintf('%.2e',real(gamma)) ' 1/km ' vz(sign(imag(gamma))+2) sprintf('j %.2e',abs(imag(gamma))) ' rad/km'];
                    app.parameter4WertLabel.Text = sprintf('%.1f km',2*pi/imag(gamma));
                    app.parameter5WertLabel.Text = [sprintf('%.3f',2*pi*f/(299792.458*imag(gamma))) '$\cdot c_0$'];
                    app.parameter6WertLabel.Text = [sprintf('%.1f',1000000*l*imag(gamma)/(2*pi*f)) ' $\mu s$'];
                    app.Tau = l*imag(gamma)/(2*pi*f);
                    app.parameter7WertLabel.Text = sprintf('%.1f kW',Pn);
            end

            if app.SimulationszeitSwitch.Value == 0
                SimulationszeitTauEditFieldValueChanged(app);
            else
                SimulationszeitTEditFieldValueChanged(app);
            end
        end

        % Value changed function: SimulationszeitTauEditField
        function SimulationszeitTauEditFieldValueChanged(app, event)
            ttau = app.SimulationszeitTauEditField.Value;
            if ~isnan(ttau*app.Tau)
                app.SimulationszeitTEditField.Value = ttau*app.Tau;
            end
            app.ErgebnisdarstellungPanel.Enable = "off";
        end

        % Value changed function: SimulationszeitTEditField
        function SimulationszeitTEditFieldValueChanged(app, event)
            t = app.SimulationszeitTEditField.Value;
            if ~isnan(t/app.Tau)
                app.SimulationszeitTauEditField.Value = t/app.Tau;
            end
            app.ErgebnisdarstellungPanel.Enable = "off";
        end

        % Value changed function: SimulationszeitSwitch
        function SimulationszeitSwitchValueChanged(app, event)
            T_type = app.SimulationszeitSwitch.Value;
            if T_type == 0
                app.SimulationszeitTauEditField.Editable = "on";
                app.SimulationszeitTEditField.Editable = "off";
            else
                app.SimulationszeitTauEditField.Editable = "off";
                app.SimulationszeitTEditField.Editable = "on";
            end
        end

        % Value changed function: SpracheSwitch
        function SpracheSwitchValueChanged(app, event)
            language = app.SpracheSwitch.Value;
            if language == 0
                app.TStrings = EnglishStrings;
                app.LeitungsauswahlDropDown.Items = cellstr(app.LeitungsparameterDatenbank.DropDownNameE);
            else
                app.TStrings = GermanStrings;
                app.LeitungsauswahlDropDown.Items = cellstr(app.LeitungsparameterDatenbank.DropDownName);
            end

            app.SpannungsquellePanel.Title = app.TStrings.voltagesource;
            app.LeitungsparameterPanel.Title = app.TStrings.lineparameter;
            app.BehandlungdesLeitungsendesPanel.Title = app.TStrings.linetermination;
            app.SimulationsparameterPanel.Title = app.TStrings.simulationparameter;
            app.ErgebnisdarstellungPanel.Title = app.TStrings.presentationofresults;
            app.EinstellungenPanel.Title = app.TStrings.settings;

            U_type = str2double(app.ArtDerSpannungsquelleSwitch.Value);
            if U_type == 0
                app.WellenparameterautomatischberechnetPanel.Title = app.TStrings.waveparameterlossless;
            else
                app.WellenparameterautomatischberechnetPanel.Title = app.TStrings.waveparameter;
            end
            
            app.LeitungsauswahlDropDownLabel.Text = app.TStrings.linechoose;
            app.GesamtlngeEditFieldLabel.Text = app.TStrings.totallength;
            app.AbschlussimpedanzDropDown.Items = {app.TStrings.openloop, app.TStrings.shortcircuit, 'R + jX', ['Z' char(183) 'e^j' char(966)], ['R + j' char(969) 'L'], ['R + 1/j' char(969) 'C']};
            app.SimulationstartenButton.Text = app.TStrings.startsimulation;
            app.SimulationszeitEditFieldLabel.Text = app.TStrings.simulationtime;

            app.SchwingungamAnfangEndederLeitungLabel.Text = app.TStrings.oscillationatselectedpositions;
            app.DarstellungsortDropDownLabel.Text = app.TStrings.positionofrepresentation;
            app.DarstellungsortDropDown.Items = {app.TStrings.beginningandend, app.TStrings.individualpositions};
            app.ErsteSchwingungbeicaEditFieldLabel.Text = app.TStrings.firstoscillation;
            app.ZweiteSchwingungbeicaEditFieldLabel.Text = app.TStrings.secondoscillation;
            app.AnzeigeSchwingung.Text = sprintf([char(0x21D2) app.TStrings.showoscillationsatand],app.Schwingung1_km,app.Schwingung2_km);
            app.ZeitfensterbreiteSliderLabel.Text = app.TStrings.widthoftimeframe;
            app.SimulationszeitCheckBox.Text = app.TStrings.simulationtime;
            app.WanderwelleberdieLngederLeitungLabel.Text = app.TStrings.travellingwaveoverthecompletelength;
            app.DarstellungstartenButton.Text = app.TStrings.startpresentation;
            
            app.SpracheSwitchLabel.Text = app.TStrings.language;
            app.AutorenLabel.Text = app.TStrings.authors;
            
            if contains(app.StatusLabel.Text,'ready')||contains(app.StatusLabel.Text,'bereit') 
                app.StatusLabel.Text = app.TStrings.ready;
            end
            drawnow;
        end

        % Value changed function: Abschlussimpedanz_1EditField, 
        % ...and 3 other components
        function SimulationsParameterValueChanged(app, event)
            app.ErgebnisdarstellungPanel.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TravellingWaveUIFigure = uifigure('Visible', 'off');
            app.TravellingWaveUIFigure.Name = 'Travelling Wave';
            app.LeitungsparameterPanel = uipanel(app.TravellingWaveUIFigure);
            app.LeitungsparameterPanel.Title = 'Leitungsparameter';
            app.LeitungImage = uiimage(app.LeitungsparameterPanel);
            app.LeitungImage.ImageSource = fullfile(pathToMLAPP, 'Leitung.png');
            app.LeitungsauswahlDropDown = uidropdown(app.LeitungsparameterPanel);
            app.LeitungsauswahlDropDown.Items = {};
            app.LeitungsauswahlDropDown.ValueChangedFcn = createCallbackFcn(app, @LeitungsauswahlDropDownValueChanged, true);
            app.LeitungsauswahlDropDown.Value = {};
            app.LeitungsauswahlDropDownLabel = uilabel(app.LeitungsparameterPanel);
            app.LeitungsauswahlDropDownLabel.Text = 'Leitungsauswahl';
            app.REditField = uieditfield(app.LeitungsparameterPanel, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.REditFieldLabel = uilabel(app.LeitungsparameterPanel);
            app.REditFieldLabel.Interpreter = 'latex';
            app.REditFieldLabel.Text = 'R'' =';
            app.OmegakmLabel = uilabel(app.LeitungsparameterPanel);
            app.OmegakmLabel.Interpreter = 'latex';
            app.OmegakmLabel.Text = '$\Omega$/km';
            app.GEditField = uieditfield(app.LeitungsparameterPanel, 'numeric');
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.GLabel = uilabel(app.LeitungsparameterPanel);
            app.GLabel.Interpreter = 'latex';
            app.GLabel.Text = 'G'' =';
            app.SkmLabel = uilabel(app.LeitungsparameterPanel);
            app.SkmLabel.Interpreter = 'latex';
            app.SkmLabel.Text = 'S/km';
            app.CEditField = uieditfield(app.LeitungsparameterPanel, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.CLabel = uilabel(app.LeitungsparameterPanel);
            app.CLabel.Interpreter = 'latex';
            app.CLabel.Text = 'C'' =';
            app.FkmLabel = uilabel(app.LeitungsparameterPanel);
            app.FkmLabel.Interpreter = 'latex';
            app.FkmLabel.Text = 'F/km';
            app.LEditField = uieditfield(app.LeitungsparameterPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.LeitungsparameterPanel);
            app.LEditFieldLabel.Interpreter = 'latex';
            app.LEditFieldLabel.Text = 'L'' =';
            app.HkmLabel = uilabel(app.LeitungsparameterPanel);
            app.HkmLabel.Interpreter = 'latex';
            app.HkmLabel.Text = 'H/km';
            app.GesamtlngeEditField = uieditfield(app.LeitungsparameterPanel, 'numeric');
            app.GesamtlngeEditField.ValueChangedFcn = createCallbackFcn(app, @GesamtlngeEditFieldValueChanged, true);
            app.GesamtlngeEditField.Value = 1;
            app.GesamtlngeEditFieldLabel = uilabel(app.LeitungsparameterPanel);
            app.GesamtlngeEditFieldLabel.Text = 'Gesamtlänge';
            app.kmLabel = uilabel(app.LeitungsparameterPanel);
            app.kmLabel.Text = 'km';
            app.StatusLabel = uilabel(app.TravellingWaveUIFigure);
            app.StatusLabel.Text = 'Status:';
            app.SpannungsquellePanel = uipanel(app.TravellingWaveUIFigure);
            app.SpannungsquellePanel.Title = 'Spannungsquelle';
            app.ArtDerSpannungsquelleSwitch = uiswitch(app.SpannungsquellePanel, 'slider');
            app.ArtDerSpannungsquelleSwitch.Items = {'DC', 'AC'};
            app.ArtDerSpannungsquelleSwitch.ItemsData = {'0', '1'};
            app.ArtDerSpannungsquelleSwitch.ValueChangedFcn = createCallbackFcn(app, @ArtDerSpannungsquelleSwitchValueChanged, true);
            app.ArtDerSpannungsquelleSwitch.Value = '0';
            app.fLabel = uilabel(app.SpannungsquellePanel);
            app.fLabel.Visible = 'off';
            app.fLabel.Text = 'f =';
            app.FrequenzEditField = uieditfield(app.SpannungsquellePanel, 'numeric');
            app.FrequenzEditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.FrequenzEditField.Editable = 'off';
            app.FrequenzEditField.Visible = 'off';
            app.HzLabel = uilabel(app.SpannungsquellePanel);
            app.HzLabel.Visible = 'off';
            app.HzLabel.Text = 'Hz';
            app.SpannungEditField = uieditfield(app.SpannungsquellePanel, 'numeric');
            app.SpannungEditField.ValueChangedFcn = createCallbackFcn(app, @LeitungsParameterEditFieldValueChanged, true);
            app.kVLabel = uilabel(app.SpannungsquellePanel);
            app.kVLabel.Text = 'kV';
            app.ULabel = uilabel(app.SpannungsquellePanel);
            app.ULabel.Text = 'U =';
            app.Quellenimpedanz_2EditField = uieditfield(app.SpannungsquellePanel, 'numeric');
            app.Quellenimpedanz_2EditField.ValueChangedFcn = createCallbackFcn(app, @SimulationsParameterValueChanged, true);
            app.Quellenimpedanz_2EditField.Editable = 'off';
            app.OmegaquadjLabel = uilabel(app.SpannungsquellePanel);
            app.OmegaquadjLabel.Interpreter = 'latex';
            app.OmegaquadjLabel.Text = '$\Omega\quad$+j';
            app.OmegaLabel_3 = uilabel(app.SpannungsquellePanel);
            app.OmegaLabel_3.Interpreter = 'latex';
            app.OmegaLabel_3.Text = '$\Omega$';
            app.ZLabel = uilabel(app.SpannungsquellePanel);
            app.ZLabel.Interpreter = 'latex';
            app.ZLabel.Text = 'Z =';
            app.Quellenimpedanz_1EditField = uieditfield(app.SpannungsquellePanel, 'numeric');
            app.Quellenimpedanz_1EditField.ValueChangedFcn = createCallbackFcn(app, @SimulationsParameterValueChanged, true);
            app.Quellenimpedanz_1EditField.Editable = 'off';
            app.QuellenimpedanzDropDown = uidropdown(app.SpannungsquellePanel);
            app.QuellenimpedanzDropDown.Items = {};
            app.QuellenimpedanzDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.QuellenimpedanzDropDown.ValueChangedFcn = createCallbackFcn(app, @QuellenimpedanzDropDownValueChanged, true);
            app.QuellenimpedanzDropDown.Value = {};
            app.QuelleImage = uiimage(app.SpannungsquellePanel);
            app.QuelleImage.ImageSource = fullfile(pathToMLAPP, 'Quelle_DC.png');
            app.BehandlungdesLeitungsendesPanel = uipanel(app.TravellingWaveUIFigure);
            app.BehandlungdesLeitungsendesPanel.Title = 'Behandlung des Leitungsendes';
            app.LeitungsendeImage = uiimage(app.BehandlungdesLeitungsendesPanel);
            app.LeitungsendeImage.ImageSource = fullfile(pathToMLAPP, 'Leitungsende_Leerlauf.png');
            app.AbschlussimpedanzDropDown = uidropdown(app.BehandlungdesLeitungsendesPanel);
            app.AbschlussimpedanzDropDown.Items = {};
            app.AbschlussimpedanzDropDown.ValueChangedFcn = createCallbackFcn(app, @AbschlussimpedanzDropDownValueChanged, true);
            app.AbschlussimpedanzDropDown.Value = {};
            app.ZLabel_2 = uilabel(app.BehandlungdesLeitungsendesPanel);
            app.ZLabel_2.Interpreter = 'latex';
            app.ZLabel_2.Visible = 'off';
            app.ZLabel_2.Text = 'Z=';
            app.Abschlussimpedanz_1EditField = uieditfield(app.BehandlungdesLeitungsendesPanel, 'numeric');
            app.Abschlussimpedanz_1EditField.ValueChangedFcn = createCallbackFcn(app, @SimulationsParameterValueChanged, true);
            app.Abschlussimpedanz_1EditField.Editable = 'off';
            app.Abschlussimpedanz_1EditField.Visible = 'off';
            app.Abschlussimpedanz_1EditField.Value = Inf;
            app.OmegaLabel_4 = uilabel(app.BehandlungdesLeitungsendesPanel);
            app.OmegaLabel_4.Interpreter = 'latex';
            app.OmegaLabel_4.Visible = 'off';
            app.OmegaLabel_4.Text = '$\Omega$ ';
            app.Abschlussimpedanz_2EditField = uieditfield(app.BehandlungdesLeitungsendesPanel, 'numeric');
            app.Abschlussimpedanz_2EditField.ValueChangedFcn = createCallbackFcn(app, @SimulationsParameterValueChanged, true);
            app.Abschlussimpedanz_2EditField.Editable = 'off';
            app.Abschlussimpedanz_2EditField.Visible = 'off';
            app.Abschlussimpedanz_2EditField.Value = Inf;
            app.OmegaLabel_5 = uilabel(app.BehandlungdesLeitungsendesPanel);
            app.OmegaLabel_5.Interpreter = 'latex';
            app.OmegaLabel_5.Visible = 'off';
            app.OmegaLabel_5.Text = '$\Omega$';
            app.WellenparameterautomatischberechnetPanel = uipanel(app.TravellingWaveUIFigure);
            app.WellenparameterautomatischberechnetPanel.Title = 'Wellenparameter (automatisch berechnet)';
            app.parameter1Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter1Label.Interpreter = 'latex';
            app.parameter1Label.Text = '$\underline{Z}_W=$';
            app.parameter1WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter1WertLabel.Interpreter = 'latex';
            app.parameter1WertLabel.Text = '';
            app.parameter7Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter7Label.Interpreter = 'latex';
            app.parameter7Label.Text = '$P_{nat}=$';
            app.parameter7WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter7WertLabel.Interpreter = 'latex';
            app.parameter7WertLabel.Text = '';
            app.parameter3Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter3Label.Interpreter = 'latex';
            app.parameter3Label.Text = '$\underline\gamma=$';
            app.parameter4Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter4Label.Interpreter = 'latex';
            app.parameter4Label.Text = '$\lambda=$';
            app.parameter6Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter6Label.Interpreter = 'latex';
            app.parameter6Label.Text = '$\tau=$';
            app.parameter2Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter2Label.Interpreter = 'latex';
            app.parameter2Label.Text = '$|\underline{Z}_W|=$';
            app.parameter2WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter2WertLabel.Interpreter = 'latex';
            app.parameter2WertLabel.Text = '';
            app.parameter3WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter3WertLabel.Interpreter = 'latex';
            app.parameter3WertLabel.Text = '';
            app.parameter4WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter4WertLabel.Interpreter = 'latex';
            app.parameter4WertLabel.Text = '';
            app.parameter6WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter6WertLabel.Interpreter = 'latex';
            app.parameter6WertLabel.Text = '';
            app.parameter5Label = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter5Label.Interpreter = 'latex';
            app.parameter5Label.Text = '$v=$';
            app.parameter5WertLabel = uilabel(app.WellenparameterautomatischberechnetPanel);
            app.parameter5WertLabel.Interpreter = 'latex';
            app.parameter5WertLabel.Text = '';
            app.SimulationsparameterPanel = uipanel(app.TravellingWaveUIFigure);
            app.SimulationsparameterPanel.Title = 'Simulationsparameter';
            app.SimulationszeitTEditField = uieditfield(app.SimulationsparameterPanel, 'numeric');
            app.SimulationszeitTEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationszeitTEditFieldValueChanged, true);
            app.SimulationszeitTEditField.Editable = 'off';
            app.sLabel = uilabel(app.SimulationsparameterPanel);
            app.sLabel.Text = 's';
            app.SimulationstartenButton = uibutton(app.SimulationsparameterPanel, 'push');
            app.SimulationstartenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationstartenButtonPushed, true);
            app.SimulationstartenButton.Text = 'Simulation starten';
            app.SimulationszeitEditFieldLabel = uilabel(app.SimulationsparameterPanel);
            app.SimulationszeitEditFieldLabel.Text = 'Simulationszeit';
            app.SimulationszeitTauEditField = uieditfield(app.SimulationsparameterPanel, 'numeric');
            app.SimulationszeitTauEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationszeitTauEditFieldValueChanged, true);
            app.SimulationszeitTauEditField.Value = 1;
            app.cdottauLabel = uilabel(app.SimulationsparameterPanel);
            app.cdottauLabel.Interpreter = 'latex';
            app.cdottauLabel.Text = '$\cdot\tau$';
            app.SimulationszeitSwitch = uiswitch(app.SimulationsparameterPanel, 'slider');
            app.SimulationszeitSwitch.Items = {'tau', 's'};
            app.SimulationszeitSwitch.ItemsData = [0 1];
            app.SimulationszeitSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationszeitSwitchValueChanged, true);
            app.SimulationszeitSwitch.Value = 0;
            app.ErgebnisdarstellungPanel = uipanel(app.TravellingWaveUIFigure);
            app.ErgebnisdarstellungPanel.Enable = 'off';
            app.ErgebnisdarstellungPanel.Title = 'Ergebnisdarstellung';
            app.SchwingungamAnfangEndederLeitungLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.SchwingungamAnfangEndederLeitungLabel.Text = 'Schwingung am Anfang / Ende der Leitung';
            app.ZeitfensterbreiteSliderLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.ZeitfensterbreiteSliderLabel.Text = 'Zeitfensterbreite';
            app.ZeitfensterbreiteSlider = uislider(app.ErgebnisdarstellungPanel);
            app.ZeitfensterbreiteSlider.Limits = [0 40];
            app.ZeitfensterbreiteSlider.ValueChangedFcn = createCallbackFcn(app, @ZeitfensterbreiteSliderValueChanged, true);
            app.ZeitfensterbreiteSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.ZeitfensterbreiteSlider.Value = 2;
            app.DarstellungsortDropDownLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.DarstellungsortDropDownLabel.Text = 'Darstellungsort';
            app.DarstellungsortDropDown = uidropdown(app.ErgebnisdarstellungPanel);
            app.DarstellungsortDropDown.Items = {'Anfang & Ende', 'individuelle Positonen'};
            app.DarstellungsortDropDown.ItemsData = {'0', '1'};
            app.DarstellungsortDropDown.ValueChangedFcn = createCallbackFcn(app, @DarstellungsortDropDownValueChanged, true);
            app.DarstellungsortDropDown.Value = '0';
            app.msLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.msLabel.Text = '0 ms';
            app.ErsteSchwingungbeicaEditField = uieditfield(app.ErgebnisdarstellungPanel, 'numeric');
            app.ErsteSchwingungbeicaEditField.ValueChangedFcn = createCallbackFcn(app, @ErsteSchwingungbeicaEditFieldValueChanged, true);
            app.ErsteSchwingungbeicaEditField.Editable = 'off';
            app.ErsteSchwingungbeicaEditFieldLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.ErsteSchwingungbeicaEditFieldLabel.Text = 'Erste Schwingung bei ca.';
            app.ZweiteSchwingungbeicaEditField = uieditfield(app.ErgebnisdarstellungPanel, 'numeric');
            app.ZweiteSchwingungbeicaEditField.ValueChangedFcn = createCallbackFcn(app, @ZweiteSchwingungbeicaEditFieldValueChanged, true);
            app.ZweiteSchwingungbeicaEditField.Editable = 'off';
            app.ZweiteSchwingungbeicaEditFieldLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.ZweiteSchwingungbeicaEditFieldLabel.Text = 'Zweite Schwingung bei ca.';
            app.kmmin0kmLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.kmmin0kmLabel.Text = 'km (min. 0 km)';
            app.kmmax0kmLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.kmmax0kmLabel.Text = 'km (max. 0 km)';
            app.AnzeigeSchwingung = uilabel(app.ErgebnisdarstellungPanel);
            app.AnzeigeSchwingung.Text = '=> Anzeige der Schwingungen bei 0 km und 0 km';
            app.SimulationszeitCheckBox = uicheckbox(app.ErgebnisdarstellungPanel);
            app.SimulationszeitCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationszeitCheckBoxValueChanged, true);
            app.SimulationszeitCheckBox.Text = 'Simulationszeit';
            app.SimulationszeitCheckBox.Value = true;
            app.WanderwelleberdieLngederLeitungLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.WanderwelleberdieLngederLeitungLabel.Text = 'Wanderwelle über die Länge der Leitung ';
            app.ZeitlupenfaktorLabel = uilabel(app.ErgebnisdarstellungPanel);
            app.ZeitlupenfaktorLabel.Text = 'Zeitlupenfaktor';
            app.DarstellungstartenButton = uibutton(app.ErgebnisdarstellungPanel, 'push');
            app.DarstellungstartenButton.ButtonPushedFcn = createCallbackFcn(app, @DarstellungstartenButtonPushed, true);
            app.DarstellungstartenButton.Text = 'Darstellung starten';
            app.sLabel_2 = uilabel(app.ErgebnisdarstellungPanel);
            app.sLabel_2.Text = 's';
            app.ZeitlupenfaktorSpinner = uispinner(app.ErgebnisdarstellungPanel);
            app.ZeitlupenfaktorSpinner.Step = 0.1;
            app.ZeitlupenfaktorSpinner.ValueDisplayFormat = '%.1f';
            app.ZeitlupenfaktorSpinner.Value = 1;
            app.Image = uiimage(app.TravellingWaveUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DHBW_transparent.png');
            app.EinstellungenPanel = uipanel(app.TravellingWaveUIFigure);
            app.EinstellungenPanel.Title = 'Einstellungen';
            app.SpracheSwitchLabel = uilabel(app.EinstellungenPanel);
            app.SpracheSwitchLabel.Text = 'Sprache';
            app.SpracheSwitch = uiswitch(app.EinstellungenPanel, 'slider');
            app.SpracheSwitch.Items = {'EN', 'GER'};
            app.SpracheSwitch.ItemsData = [0 1];
            app.SpracheSwitch.ValueChangedFcn = createCallbackFcn(app, @SpracheSwitchValueChanged, true);
            app.SpracheSwitch.Value = 0;
            app.AutorenLabel = uilabel(app.TravellingWaveUIFigure);
            app.AutorenLabel.Text = 'Autoren:';
            app.StefanSchtzhoffLabel = uilabel(app.TravellingWaveUIFigure);
            app.StefanSchtzhoffLabel.Text = 'Stefan Schützhoff';
            app.ArnyWolfgramLabel = uilabel(app.TravellingWaveUIFigure);
            app.ArnyWolfgramLabel.Text = 'Arny Wolfgram';
            app.ProfDrAndreasKilthauLabel = uilabel(app.TravellingWaveUIFigure);
            app.ProfDrAndreasKilthauLabel.Text = 'Prof. Dr. Andreas Kilthau';
            % Show the figure after all components are created
            app.TravellingWaveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wanderwellen_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TravellingWaveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TravellingWaveUIFigure)
        end
    end
end